classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz              matlab.ui.Figure
        voltaje               matlab.ui.control.NumericEditField
        VoltajedefiguraLabel  matlab.ui.control.Label
        errorusuario          matlab.ui.control.Spinner
        ErrorSpinnerLabel     matlab.ui.control.Label
        iteraciones           matlab.ui.control.Table
        Image                 matlab.ui.control.Image
        Calcular              matlab.ui.control.Button
        tamano                matlab.ui.control.NumericEditField
        TamaoLabel            matlab.ui.control.Label
    end

    %Se crea un objeto que no se muestra en el GUI
    properties (Access = private)
        Tabla_respaldo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Se definen que los valores a ingresar en la tabla son
            %numericos y se crea la fila inicial
            
        end

        % Value changed function: tamano
        function tamanoValueChanged(app, event)
           
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
      %Clearing variables in memory and Matlab command screen

      N = app.tamano.Value;
      app.iteraciones.Data = cell(N, N);
        %Dimensions of the simulation grid in x (xdim) and y (ydim) directions
        xdim=N;
        ydim=N;

%Initializing previous (V_prev) and present (V_now) voltage matrices
V_now=zeros(xdim+1,ydim+1);
V_prev=zeros(xdim+1,ydim+1);

%A voltage of 45*x*(1-x) is applied on one boundary, the remaining
%boundaries are going to remain at zero volts
V_now(1 ,2: xdim)=100;
li = xdim/2;
ld = xdim/2 + 1;
i = 0;

while 1
    
    %Actualizaciòn de la fila
    fila = xdim/4 + ( 1 + 2*i );
    
    %Verificar que la fila no hay alcanzado su tope
    if ( fila == 3*(xdim/4) - 1 )
        break;
    end
   
    %Reemplazo de valores de voltaje constante
    V_now(fila:fila+1,li:ld ) = 100 * ones( 2 , abs(ld - li) + 1 );
   
    %Actulizaciòn de los lìmites laterales
    i = i + 1;
    li = li - 1;
    ld = ld + 1;
end

%Iteration counter
iter=0;

%Calculation of maximum error between V_now and V_prev at all points
%By setting the applied voltage for only V_now, we have made V_no and
%V_prev different, hence error will be greater than zero and the program
%will enter the while loop following this command.
error = max(max(abs(V_now-V_prev)));

%Iteration loop
while(error>app.errorusuario.Value())%Run this until convergence
    
    iter=iter+1; % Iteration counter increment
    
    % Updating present iteration using 4 point Central diffrence form
    % of Laplace equation obtained using Finite Difference method
    for i=2:1:xdim
        for j=2:1:ydim
        V_now(i,j)=(V_now(i-1,j)+V_now(i+1,j)+V_now(i,j-1)+V_now(i,j+1))/4;
        end
    end
    li = xdim/2;
            ld = xdim/2 + 1;
            i = 0;
            while 1
        
            %Actualizaciòn de la fila
            fila = xdim/4 + ( 1 + 2*i );
    
            %Verificar que la fila no hay alcanzado su tope
            if ( fila == 3*(xdim/4) - 1 )
            break;
            end
   
            %Reemplazo de valores de voltaje constante
            V_now(fila:fila+1,li:ld ) = app.voltaje.Value * ones( 2 , abs(ld - li) + 1 );
   
            %Actulizaciòn de los lìmites laterales
            i = i + 1;
            li = li - 1;
            ld = ld + 1;
            end
            
    error=max(max(abs(V_now-V_prev))); % Calculate the maximum error between previous and current iteration at all points
    V_prev=V_now; % Updating previous iteration matrix to the last iteration performed
    app.iteraciones.Data = V_now(:,:);
    
    
    %Movie type colour scaled image plot to see how solution progresses    
    imagesc(V_now);colorbar;
    title(['Distribución de Voltaje en una malla de ',int2str(xdim),' x ',int2str(ydim),' en la iteración número ',int2str(iter)],'Color','k'); 
    drawnow;
    
    
end
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'Bidimensional Laplace Equation Solver';
            app.interfaz.Icon = 'ico.webp';
            app.TamaoLabel = uilabel(app.interfaz);
            app.TamaoLabel.Text = 'Tamaño';
            app.tamano = uieditfield(app.interfaz, 'numeric');
            app.tamano.Limits = [4 Inf];
            app.tamano.RoundFractionalValues = 'on';
            app.tamano.ValueChangedFcn = createCallbackFcn(app, @tamanoValueChanged, true);
            app.tamano.Value = 4;
            app.Calcular = uibutton(app.interfaz, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Text = 'Graficar';
            app.Image = uiimage(app.interfaz);
            app.Image.ImageSource = 'escudo-inge-03.png';
            app.iteraciones = uitable(app.interfaz);
            app.iteraciones.ColumnName = '';
            app.iteraciones.RowName = {};
            app.ErrorSpinnerLabel = uilabel(app.interfaz);
            app.ErrorSpinnerLabel.Text = 'Error';
            app.errorusuario = uispinner(app.interfaz);
            app.errorusuario.Step = 0.01;
            app.errorusuario.Limits = [0 1];
            app.VoltajedefiguraLabel = uilabel(app.interfaz);
            app.VoltajedefiguraLabel.Text = 'Voltaje de figura';
            app.voltaje = uieditfield(app.interfaz, 'numeric');
            app.voltaje.Limits = [4 Inf];
            app.voltaje.RoundFractionalValues = 'on';
            app.voltaje.Value = 4;
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end